classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RawFileEditFieldLabel   matlab.ui.control.Label
        RawFileEditField        matlab.ui.control.EditField
        BrowseButton            matlab.ui.control.Button
        OutputtoEditFieldLabel  matlab.ui.control.Label
        OutputtoEditField       matlab.ui.control.EditField
        DownloadButton          matlab.ui.control.Button
        UnprocessedImage        matlab.ui.control.UIAxes
        BrowseButton_2          matlab.ui.control.Button
        RawImageLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
    
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UnprocessedImage, []);
            xlabel(app.UnprocessedImage, []);
            ylabel(app.UnprocessedImage, []);
            app.UnprocessedImage.XAxis.TickLabels = {};
            app.UnprocessedImage.YAxis.TickLabels = {};
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, path] = uigetfile;    %we can force this to look for a certain file type, but I will leave this open for now
            FullRawPath = strcat(path, filename);
            app.RawImageLabel.Text=(filename);
            imshow(FullRawPath, 'Parent', app.UnprocessedImage)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RawFileEditFieldLabel = uilabel(app.UIFigure);
            app.RawFileEditFieldLabel.Text = 'Raw File:';
            app.RawFileEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.OutputtoEditFieldLabel = uilabel(app.UIFigure);
            app.OutputtoEditFieldLabel.Text = 'Output to:';
            app.OutputtoEditField = uieditfield(app.UIFigure, 'text');
            app.DownloadButton = uibutton(app.UIFigure, 'push');
            app.DownloadButton.Text = 'Download';
            app.UnprocessedImage = uiaxes(app.UIFigure);
            app.BrowseButton_2 = uibutton(app.UIFigure, 'push');
            app.BrowseButton_2.Text = 'Browse';
            app.RawImageLabel = uilabel(app.UIFigure);
            app.RawImageLabel.Text = 'No Image Found';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end